classdef ThreeBandEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveAudioButton                matlab.ui.control.Button
        LowCutOffFreqKnob              matlab.ui.control.Knob
        LowFrequencyCutOffHzKnobLabel  matlab.ui.control.Label
        LowCutOffText                  matlab.ui.control.NumericEditField
        HighCutOffFreqKnob             matlab.ui.control.Knob
        HighFrequencyCutOffHzLabel     matlab.ui.control.Label
        HighCutOffText                 matlab.ui.control.NumericEditField
        LoudnessGainSwitch             matlab.ui.control.ToggleSwitch
        LoudnessGainSwitchLabel        matlab.ui.control.Label
        LoudnessGainLamp               matlab.ui.control.Lamp
        HighDBText                     matlab.ui.control.NumericEditField
        HighDBKnob                     matlab.ui.control.Knob
        HighFrequenciesdBKnobLabel     matlab.ui.control.Label
        MidDBText                      matlab.ui.control.NumericEditField
        MidDBKnob                      matlab.ui.control.Knob
        MidFrequenciesdBKnobLabel      matlab.ui.control.Label
        LowDBText                      matlab.ui.control.NumericEditField
        LowDBKnob                      matlab.ui.control.Knob
        LowFrequenciesdBKnobLabel      matlab.ui.control.Label
        ThreeBandEqualizerLabel        matlab.ui.control.Label
        StopAudioButton                matlab.ui.control.Button
        PauseAudioButton               matlab.ui.control.Button
        PlayAudioButton                matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        FileNameTextBar                matlab.ui.control.EditField
    end

    
    properties (Access = private)

        % Audio File Information
        audio_filename      % Filename of audio file
        audio_filepath      % Path of audio file
        audio_file_ext      % Audio file extension format
        audio_file          % The audio file itself
        audiofile_save      % The file to save
        audiofile_savepath  % FIle path to save to
        audiofile_final_write   % Audio file to write

        % Audio Samples and Information
        input_audio_samples % Samples of audio
        sample_rate         % Sample rate of audio
        processed_audio     % Checked audio samples

        % Audio Playback Information
        audio_playback      % Final Object to play
        audio_play_file     % File to play
        audio_paused_flag   % Flag if audio is paused

        % Loudness Gain Information
        loudness_gain_flag  % State of loudness switch

        % Frequency Information
        total_response      % Total filter response
        angular_response    % Filter response of angular freq
        low_response        % Filter response of low shelf
        mid_response        % Filter response of mid peak
        high_response       % Filter response for high shelf

        lowcutoff_val       % Low Cut Off Freq Val
        highcutoff_val      % High Cut Off Freq Val
        bandwidth_val       % Bandwidth value (High - Low)
    end
    
    methods (Access = private)
        
        function [] = resetParameters(app)

            % Knobs
            app.LowDBKnob.Value = 0;
            app.MidDBKnob.Value = 0;
            app.HighDBKnob.Value = 0;
            % Texts
            app.LowDBText.Value = 0;
            app.MidDBText.Value = 0;
            app.HighDBText.Value = 0;

            % Knobs
            app.LowCutOffFreqKnob.Value = 2525;
            app.HighCutOffFreqKnob.Value = 10000;
            % Texts
            app.LowCutOffText.Value = 2525;
            app.HighCutOffText.Value = 10000;

            app.LoudnessGainSwitch.Value = 'Off';
            app.LoudnessGainLamp.Color = 'red';

            app.low_response = ones(size(app.angular_response));
            app.mid_response = ones(size(app.angular_response));
            app.high_response = ones(size(app.angular_response));
        end
        
        function [a, b] = Shelving(app, filterType, gainValue)

            q_val = 1 / 0.8;

            if abs(gainValue) <= 0
                a = 1;
                b = 1;
            else
                if (strcmp(filterType, 'l'))
                    K = tan( (pi * app.lowcutoff_val) / app.sample_rate);
                else
                    K = tan( (pi * app.highcutoff_val) / app.sample_rate);
                end

                V0 = 10^(gainValue / 20);
                
                % Low Boost
                if (gainValue >= 0 && strcmp(filterType, 'l'))
                    
                    a1 = 2 * (K^2 - 1) / (1 + q_val * K + K^2);
                    a2 = (1 - q_val * K + K^2) / (1 + q_val * K + K^2);
                    b0 = (1 + (sqrt(V0) * q_val) * K + V0 * K^2) / (1 + q_val * K + K^2);
                    b1 = 2 * (V0 * K^2 - 1) / (1 + q_val * K + K^2);
                    b2 = (1 - (sqrt(V0) * q_val) * K + V0 * K^2) / (1 + q_val * K + K^2);
    
                % Low Cut
                elseif (gainValue < 0 && strcmp(filterType, 'l'))
                    
                    a1 = 2 * (K^2 - V0) / (V0 + (sqrt(V0) * q_val) * K + K^2);
                    a2 = (V0 - (sqrt(V0) * q_val) * K + K^2) / (V0 + (sqrt(V0) * q_val) * K + K^2);
                    b0 = V0 * (1 + q_val * K + K^2) / (V0 + (sqrt(V0) * q_val) * K + K^2);
                    b1 = 2 * V0 * (K^2 - 1) / (V0 + (sqrt(V0) * q_val) * K + K^2);
                    b2 = V0 * (1 - q_val * K + K^2) / (V0 + (sqrt(V0) * q_val) * K + K^2);
                    
                % High Boost
                elseif (gainValue >= 0 && strcmp(filterType, 'h'))
                    
                    a1 = 2 * (K^2 - 1) / (1 + q_val * K + K^2);
                    a2 = (1 - q_val * K + K^2)/(1 + q_val * K + K^2);
                    b0 = (V0 + (sqrt(V0) * q_val) * K + K^2) / (1 + q_val * K + K^2);
                    b1 = 2 * (K^2 - V0) / (1 + q_val * K + K^2);
                    b2 = (V0 - (sqrt(V0) * q_val) * K + K^2) / (1 + q_val * K + K^2);
                
                %High Cut
                else
                    
                    a1 = 2 * (V0 * K^2 - 1) / (1 + (sqrt(V0) * q_val) * K + V0 * K^2);
                    a2 = (1 - (sqrt(V0) * q_val) * K + V0 * K^2) / (1 + (sqrt(V0) * q_val) * K + V0 * K^2);
                    b0 = V0 * (1 + q_val * K + K^2) / (1 + (sqrt(V0) * q_val) * K + V0 * K^2);
                    b1 = 2 * V0 * (K^2 - 1) / (1 + sqrt(2*V0) * K + V0 * K^2);
                    b2 = V0 * (1 - q_val * K + K^2) / (1 + (sqrt(V0) * q_val) * K + V0 * K^2);

                end

                a = [1, a1, a2];
                b = [b0, b1, b2];

            end
        end
        
        function [a, b] = Peaking(app, gainValue)

            if abs(gainValue) <= 0.01
                a = 1;
                b = 1;
            else
                app.bandwidth_val = app.highcutoff_val - app.lowcutoff_val;
                K = tan(pi * app.bandwidth_val / app.sample_rate);
                V0 = 10^(gainValue / 20);
                Q = 0.8;

                if (gainValue >= 0)
                % Boost
                    
                    a1 = 2 * (K^2 - 1) / (1 + K / Q + K^2);
                    a2 = (1 - K / Q + K^2) / (1 + K / Q + K^2);
                    b0 = (1 + V0 * K / Q + K^2) / (1 + K / Q + K^2);
                    b1 = 2 * (K^2 - 1) / (1 + K / Q + K^2);
                    b2 = (1 - V0 * K / Q + K^2) / (1 + K / Q + K^2);
    
                else
                % Cut
                    
                    a1 = 2 * (K^2 - 1) / (1 + K / (V0 * Q) + K^2);
                    a2 = (1 - K / (V0 * Q) + K^2) / (1 + K / (V0 * Q) + K^2);
                    b0 = (1 + K / Q + K^2) / (1 + K / (V0 * Q) + K^2);
                    b1 = 2 * (K^2 - 1) / (1 + K / (V0 * Q) + K^2);
                    b2 = (1 - K / Q + K^2) / (1 + K / (V0 * Q) + K^2);
   
                end
                a = [1, a1, a2];
                b = [b0, b1, b2];
            end
        end
        
        function [output_audio] = Loudness(app, input_audio)
            if app.loudness_gain_flag == 0
                output_audio = input_audio;
            else
                weightFilt = weightingFilter('K-weighting', app.sample_rate);
                output_audio = weightFilt(input_audio);
            end
        end
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.input_audio_samples = 44100;
            app.audio_paused_flag = 0;
            app.loudness_gain_flag = 0;
            
            [~, app.angular_response] = freqz(1, 1, 2048);
            app.total_response = ones(size(app.angular_response));
            app.low_response = ones(size(app.angular_response));
            app.mid_response = ones(size(app.angular_response));
            app.high_response = ones(size(app.angular_response));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if isempty(app.input_audio_samples)
                uialert(app.UIFigure,'Unable to play file! File not found or corrupted.', 'File Not Found')
            else
                if strcmp(app.PlayAudioButton.Text, 'Play Audio')

                    app.total_response = app.low_response .* app.mid_response .* app.high_response;
                    final_eq = app.total_response;

                    if final_eq(:,1) == 1
                        a = 1;
                        b = 1;
                    else
                        [b, a] = invfreqz(final_eq, app.angular_response, 2, 2);
                    end
                     
                    % Process audio
                    app.processed_audio = filter(b, a, app.input_audio_samples);
                    app.processed_audio = Loudness(app, app.processed_audio);
                    app.audio_play_file = app.processed_audio ./ max(max(abs(app.processed_audio)));
                    app.audio_playback = audioplayer(app.audio_play_file, app.sample_rate);
                    play(app.audio_playback);
                    
                    % Magnitude and Frequency Plot
                    fvtool(b, a);
                 end
            end
        end

        % Button pushed function: PauseAudioButton
        function PauseAudioButtonPushed(app, event)
            app.audio_paused_flag = 1;
            pause(app.audio_playback);
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if isempty(app.input_audio_samples)
                uialert(app.UIFigure,'Unable to play file! File not found or corrupted.', 'File Not Found')
            elseif isempty(app.audio_playback)
                uialert(app.UIFigure, 'No Sound Playing!','File Not Found')
            else
                stop(app.audio_playback)
            end
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            if isempty(app.input_audio_samples)
                uialert(app.UIFigure,'Unable to save file! File does not exist.','File Not Found')
            else
                [app.audiofile_save, app.audiofile_savepath] = uiputfile({'*.wav','WAVE File (*.wav)';...
                                                                      '*.ogg','OGG File (*.ogg)'; ...
                                                                      '*.oga','OGG File (*.oga)';...
                                                                      '*.flac','FLAC File (*.flac)';},...
                                                                      'Save Audio', [app.audio_filename,'_','Filtered']);

                if ~isequal(app.audiofile_save, 0) || ~isequal(app.audiofile_savepath, 0)
                        app.total_response = app.low_response .* app.mid_response .* app.high_response;

                    if app.total_response == 1
                        b = 1;
                        a = 1;
                    else
                        [b, a] = invfreqz(app.total_response, app.angular_response, 2, 2);
                    end

                    app.processed_audio = filter(b, a, app.input_audio_samples);
                    app.processed_audio = Loudness(app, app.processed_audio);

                    app.audiofile_final_write = app.processed_audio ./ max(max(abs(app.processed_audio)));
                    audiowrite([app.audiofile_savepath, app.audiofile_save], app.audiofile_final_write, app.sample_rate);
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.audio_file, app.audio_filepath] = uigetfile({'*.wav;*.ogg;*.oga;*.flac;*.mp3;',...
                                                             'All Audio Files (*.wav,*.ogg,*.oga,*.flac,*.mp3)';...
                                                             '*.wav','WAVE File (*.wav)';...
                                                             '*.ogg;*.oga','OGG File (*.ogg,*.oga)';...
                                                             '*.flac','FLAC File (*.flac)';...
                                                             '*.mp3','MP3 File (*.mp3)'},'Browse');

             figure(app.UIFigure);
             
             if ~isequal(app.audio_file, 0)
                [app.input_audio_samples, app.sample_rate] = audioread([app.audio_filepath, app.audio_file]);

                L = length(app.input_audio_samples);
                                
                % Low, Mid and High Frequencies Boost/Cut Reset
                resetParameters(app);

                % Loaded Audio File Name
                [~, app.audio_filename, app.audio_file_ext] = fileparts([app.audio_filepath, app.audio_file]);
                app.FileNameTextBar.Value = [app.audio_filename, app.audio_file_ext];
            end
        end

        % Value changed function: LowCutOffFreqKnob
        function LowCutOffFreqKnobValueChanged(app, event)
            lowCutOffVal = app.LowCutOffFreqKnob.Value;
            app.LowCutOffText.Value = lowCutOffVal;
            app.lowcutoff_val = lowCutOffVal;

            [a, b] = Shelving(app, 'l', app.LowDBText.Value);
            [app.low_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: LowCutOffText
        function LowCutOffTextValueChanged(app, event)
            lowCutVal = app.LowCutOffText.Value;

            if lowCutVal < 50
                uialert(app.UIFigure,'Please specify a value within 50 Hz and 5000 Hz.', 'Not In Range!');
                lowCutVal = 50;
            end

            if lowCutVal > 5000
                uialert(app.UIFigure,'Please specify a value within 50 Hz and 5000 Hz.', 'Not In Range!');
                lowCutVal = 5000;
            end

            app.LowCutOffFreqKnob.Value = lowCutVal;
            app.LowCutOffText.Value = lowCutVal;
            app.lowcutoff_val = lowCutVal;

            [a, b] = Shelving(app, 'l', app.LowDBText.Value);
            [app.low_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: HighCutOffFreqKnob
        function HighCutOffFreqKnobValueChanged(app, event)
            highCutOffVal = app.HighCutOffFreqKnob.Value;
            app.HighCutOffText.Value = highCutOffVal;
            app.highcutoff_val = highCutOffVal;

            [a, b] = Shelving(app, 'h', app.HighDBText.Value);
            [app.high_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: HighCutOffText
        function HighCutOffTextValueChanged(app, event)
            highCutVal = app.HighCutOffText.Value;

            if highCutVal < 5001
                uialert(app.UIFigure,'Please specify a value within 5001 Hz and 15000 Hz.', 'Not In Range!');
                highCutVal = 5001;
            end

            if highCutVal > 15000
                uialert(app.UIFigure,'Please specify a value within 5001 Hz and 15000 Hz.', 'Not In Range!');
                highCutVal = 15000;
            end

            app.HighCutOffFreqKnob.Value = highCutVal;
            app.HighCutOffText.Value = highCutVal;
            app.highcutoff_val = highCutVal;

            [a, b] = Shelving(app, 'h', highCutVal);
            [app.high_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: LoudnessGainSwitch
        function LoudnessGainSwitchValueChanged(app, event)
            loudnessVal = app.LoudnessGainSwitch.Value;

            if strcmp(loudnessVal, 'Off')
                app.LoudnessGainLamp.Color = 'red';
                app.loudness_gain_flag = 0;
            else
                app.LoudnessGainLamp.Color = 'green';
                app.loudness_gain_flag = 1;
            end
        end

        % Value changed function: LowDBKnob
        function LowDBKnobValueChanged(app, event)
            lowDBVal = app.LowDBKnob.Value;
            app.LowDBText.Value = lowDBVal;

            [a, b] = Shelving(app, 'l', lowDBVal);
            [app.low_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: LowDBText
        function LowDBTextValueChanged(app, event)
            lowDB = app.LowDBText.Value;

            if lowDB < -15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                lowDB = -15;
            end

            if lowDB > 15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                lowDB = 15;
            end

            app.LowDBKnob.Value = lowDB;
            app.LowDBText.Value = lowDB;

            [a, b] = Shelving(app, 'l', lowDB);
            [app.low_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: MidDBKnob
        function MidDBKnobValueChanged(app, event)
            midDBVal = app.MidDBKnob.Value;
            app.MidDBText.Value = midDBVal;

            [a, b] = Peaking(app, midDBVal);
            [app.mid_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: MidDBText
        function MidDBTextValueChanged(app, event)
            midDB = app.MidDBText.Value;

            if midDB < -15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                midDB = -15;
            end

            if midDB > 15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                midDB = 15;
            end

            app.MidDBKnob.Value = midDB;
            app.MidDBText.Value = midDB;

            [a, b] = Peaking(app, midDB);
            [app.mid_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: HighDBKnob
        function HighDBKnobValueChanged(app, event)
            highDBVal = app.HighDBKnob.Value;
            app.HighDBText.Value = highDBVal;

            [a, b] = Shelving(app, 'h', highDBVal);
            [app.high_response, app.angular_response] = freqz(b, a, 2048);
        end

        % Value changed function: HighDBText
        function HighDBTextValueChanged(app, event)
            highDB = app.HighDBText.Value;

            if highDB < -15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                highDB = -15;
            end

            if highDB > 15
                uialert(app.UIFigure,'Please specify a value within -15 dB and 15 dB.', 'Not In Range!');
                highDB = 15;
            end

            app.HighDBKnob.Value = highDB;
            app.HighDBText.Value = highDB;

            [a, b] = Shelving(app, 'h', highDB);
            [app.high_response, app.angular_response] = freqz(b, a, 2048);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileNameTextBar = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.PauseAudioButton = uibutton(app.UIFigure, 'push');
            app.PauseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudioButtonPushed, true);
            app.PauseAudioButton.Text = 'Pause Audio';
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.ThreeBandEqualizerLabel = uilabel(app.UIFigure);
            app.ThreeBandEqualizerLabel.Text = 'Three Band Equalizer';
            app.LowFrequenciesdBKnobLabel = uilabel(app.UIFigure);
            app.LowFrequenciesdBKnobLabel.Text = 'Low Frequencies (dB)';
            app.LowDBKnob = uiknob(app.UIFigure, 'continuous');
            app.LowDBKnob.Limits = [-15 15];
            app.LowDBKnob.ValueChangedFcn = createCallbackFcn(app, @LowDBKnobValueChanged, true);
            app.LowDBText = uieditfield(app.UIFigure, 'numeric');
            app.LowDBText.ValueDisplayFormat = '%.2f';
            app.LowDBText.ValueChangedFcn = createCallbackFcn(app, @LowDBTextValueChanged, true);
            app.MidFrequenciesdBKnobLabel = uilabel(app.UIFigure);
            app.MidFrequenciesdBKnobLabel.Text = 'Mid Frequencies (dB)';
            app.MidDBKnob = uiknob(app.UIFigure, 'continuous');
            app.MidDBKnob.Limits = [-15 15];
            app.MidDBKnob.ValueChangedFcn = createCallbackFcn(app, @MidDBKnobValueChanged, true);
            app.MidDBText = uieditfield(app.UIFigure, 'numeric');
            app.MidDBText.ValueDisplayFormat = '%.2f';
            app.MidDBText.ValueChangedFcn = createCallbackFcn(app, @MidDBTextValueChanged, true);
            app.HighFrequenciesdBKnobLabel = uilabel(app.UIFigure);
            app.HighFrequenciesdBKnobLabel.Text = 'High Frequencies (dB)';
            app.HighDBKnob = uiknob(app.UIFigure, 'continuous');
            app.HighDBKnob.Limits = [-15 15];
            app.HighDBKnob.ValueChangedFcn = createCallbackFcn(app, @HighDBKnobValueChanged, true);
            app.HighDBText = uieditfield(app.UIFigure, 'numeric');
            app.HighDBText.ValueDisplayFormat = '%.2f';
            app.HighDBText.ValueChangedFcn = createCallbackFcn(app, @HighDBTextValueChanged, true);
            app.LoudnessGainLamp = uilamp(app.UIFigure);
            app.LoudnessGainSwitchLabel = uilabel(app.UIFigure);
            app.LoudnessGainSwitchLabel.Text = 'Loudness Gain';
            app.LoudnessGainSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LoudnessGainSwitch.ValueChangedFcn = createCallbackFcn(app, @LoudnessGainSwitchValueChanged, true);
            app.HighCutOffText = uieditfield(app.UIFigure, 'numeric');
            app.HighCutOffText.ValueDisplayFormat = '%.2f';
            app.HighCutOffText.ValueChangedFcn = createCallbackFcn(app, @HighCutOffTextValueChanged, true);
            app.HighFrequencyCutOffHzLabel = uilabel(app.UIFigure);
            app.HighFrequencyCutOffHzLabel.Text = {'High Frequency CutOff'; '(Hz)'};
            app.HighCutOffFreqKnob = uiknob(app.UIFigure, 'continuous');
            app.HighCutOffFreqKnob.Limits = [5001 15000];
            app.HighCutOffFreqKnob.MajorTicks = [5001 7000 9000 11000 13000 15000];
            app.HighCutOffFreqKnob.MajorTickLabels = {'5001', '7000', '9000', '11000', '13000', '15000'};
            app.HighCutOffFreqKnob.ValueChangedFcn = createCallbackFcn(app, @HighCutOffFreqKnobValueChanged, true);
            app.HighCutOffFreqKnob.MinorTicks = [5001 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000 10500 11000 11500 12000 12500 13000 13500 14000 14500 15000];
            app.HighCutOffFreqKnob.Value = 5001;
            app.LowCutOffText = uieditfield(app.UIFigure, 'numeric');
            app.LowCutOffText.ValueDisplayFormat = '%.2f';
            app.LowCutOffText.ValueChangedFcn = createCallbackFcn(app, @LowCutOffTextValueChanged, true);
            app.LowFrequencyCutOffHzKnobLabel = uilabel(app.UIFigure);
            app.LowFrequencyCutOffHzKnobLabel.Text = {'Low Frequency CutOff'; '(Hz)'};
            app.LowCutOffFreqKnob = uiknob(app.UIFigure, 'continuous');
            app.LowCutOffFreqKnob.Limits = [50 5000];
            app.LowCutOffFreqKnob.ValueChangedFcn = createCallbackFcn(app, @LowCutOffFreqKnobValueChanged, true);
            app.LowCutOffFreqKnob.Value = 50;
            app.SaveAudioButton = uibutton(app.UIFigure, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeBandEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end